classdef Task1_AsyaAbdelFattah_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        histogramequalizationCheckBox   matlab.ui.control.CheckBox
        IntensityBrightnessSlider       matlab.ui.control.Slider
        IntensityLabel                  matlab.ui.control.Label
        ImageEnhancementLabel           matlab.ui.control.Label
        YCbCrDropDown                   matlab.ui.control.DropDown
        YCbCrDropDownLabel              matlab.ui.control.Label
        LabDropDown                     matlab.ui.control.DropDown
        LabDropDownLabel                matlab.ui.control.Label
        HSIDropDown                     matlab.ui.control.DropDown
        HSIDropDownLabel                matlab.ui.control.Label
        RGBDropDown                     matlab.ui.control.DropDown
        RGBDropDownLabel                matlab.ui.control.Label
        imagesharpeningDropDown_2       matlab.ui.control.DropDown
        imagesharpeningDropDown_2Label  matlab.ui.control.Label
        imagesmoothingDropDown_2        matlab.ui.control.DropDown
        imagesmoothingDropDown_2Label   matlab.ui.control.Label
        imagesharpeningDropDown         matlab.ui.control.DropDown
        imagesharpeningDropDownLabel    matlab.ui.control.Label
        imagesmoothingDropDown          matlab.ui.control.DropDown
        imagesmoothingDropDownLabel     matlab.ui.control.Label
        FrequencydomainfilteringLabel   matlab.ui.control.Label
        ColorSpaceconversionLabel       matlab.ui.control.Label
        SpatialdomainFilteringLabel     matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        NumberofCornersSpinner          matlab.ui.control.Spinner
        NumberofCornersSpinnerLabel     matlab.ui.control.Label
        NeighborhoodSizeSpinner         matlab.ui.control.Spinner
        NeighborhoodSizeSpinnerLabel    matlab.ui.control.Label
        MinCornerStrengthSpinner        matlab.ui.control.Spinner
        MinCornerStrengthSpinnerLabel   matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        sigmaSlider                     matlab.ui.control.Slider
        sigmaSliderLabel                matlab.ui.control.Label
        ScaleDropDown                   matlab.ui.control.DropDown
        ScaleDropDownLabel              matlab.ui.control.Label
        OriantationDropDown             matlab.ui.control.DropDown
        OriantationDropDownLabel        matlab.ui.control.Label
        LaplacianSlider                 matlab.ui.control.Slider
        LaplacianSliderLabel            matlab.ui.control.Label
        LMDropDown                      matlab.ui.control.DropDown
        LMDropDownLabel                 matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        GaussianSlider                  matlab.ui.control.Slider
        GaussianSliderLabel             matlab.ui.control.Label
        SelectSpecificButton            matlab.ui.control.Button
        DoneHarrisButton                matlab.ui.control.Button
        CornerDetectionHarrisLabel      matlab.ui.control.Label
        DoneCannyButton                 matlab.ui.control.Button
        DoneFBButton                    matlab.ui.control.Button
        FilterBanksLabel                matlab.ui.control.Label
        EdgeDetectionCannyLabel         matlab.ui.control.Label
        TemplateMatchingLabel           matlab.ui.control.Label
        PyramidsLabel                   matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        numberofscalesSpinner           matlab.ui.control.Spinner
        numberofscalesSpinnerLabel      matlab.ui.control.Label
        ScaleFactorSlider               matlab.ui.control.Slider
        ScaleFactorSliderLabel          matlab.ui.control.Label
        sigma_DoGLoGSlider              matlab.ui.control.Slider
        sigma_DoGLoGSliderLabel         matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        DoneDoGLoGButton                matlab.ui.control.Button
        DoGLoGLabel                     matlab.ui.control.Label
        GradientmethodDropDown          matlab.ui.control.DropDown
        GradientmethodDropDownLabel     matlab.ui.control.Label
        NormalizationDropDown           matlab.ui.control.DropDown
        NormalizationDropDownLabel      matlab.ui.control.Label
        BlockSizeSpinner                matlab.ui.control.Spinner
        BlockSizeSpinnerLabel           matlab.ui.control.Label
        BinNumberSpinner                matlab.ui.control.Spinner
        BinNumberSpinnerLabel           matlab.ui.control.Label
        CellSizeSpinner                 matlab.ui.control.Spinner
        CellSizeSpinnerLabel            matlab.ui.control.Label
        DoneHoGButton                   matlab.ui.control.Button
        VisualizationCheckBox           matlab.ui.control.CheckBox
        HoGLabel                        matlab.ui.control.Label
        Tab4                            matlab.ui.container.Tab
        ApplyNoiseButton                matlab.ui.control.Button
        PolarityDropDown                matlab.ui.control.DropDown
        PolarityDropDownLabel           matlab.ui.control.Label
        ThersholdSpinner                matlab.ui.control.Spinner
        ThersholdSpinnerLabel           matlab.ui.control.Label
        MaxRadiusSpinner                matlab.ui.control.Spinner
        MaxRadiusSpinnerLabel           matlab.ui.control.Label
        MinRadiusSpinner                matlab.ui.control.Spinner
        MinRadiusSpinnerLabel           matlab.ui.control.Label
        MinLengthSpinner                matlab.ui.control.Spinner
        MinLengthSpinnerLabel           matlab.ui.control.Label
        FillGrapSpinner                 matlab.ui.control.Spinner
        FillGrapSpinnerLabel            matlab.ui.control.Label
        NumPeaksSpinner                 matlab.ui.control.Spinner
        NumPeaksSpinnerLabel            matlab.ui.control.Label
        DoneHoughCircleButton           matlab.ui.control.Button
        DoneHoughLineButton             matlab.ui.control.Button
        CriclesLabel                    matlab.ui.control.Label
        LinesLabel                      matlab.ui.control.Label
        HoughTransformLabel             matlab.ui.control.Label
        NoiseLevelSlider                matlab.ui.control.Slider
        NoiseLevelSliderLabel           matlab.ui.control.Label
        ModelTypeDropDown               matlab.ui.control.DropDown
        ModelTypeDropDownLabel          matlab.ui.control.Label
        MinimumInliersSpinner           matlab.ui.control.Spinner
        MinimumInliersSpinnerLabel      matlab.ui.control.Label
        InlierThresholdSpinner          matlab.ui.control.Spinner
        InlierThresholdSpinnerLabel     matlab.ui.control.Label
        IterationsSpinner               matlab.ui.control.Spinner
        IterationsSpinnerLabel          matlab.ui.control.Label
        DoneRANSACButton                matlab.ui.control.Button
        RANSACLabel                     matlab.ui.control.Label
        Tab5                            matlab.ui.container.Tab
        Generate3DStructureButton       matlab.ui.control.Button
        DistancethresholdSpinner        matlab.ui.control.Spinner
        DistancethresholdSpinnerLabel   matlab.ui.control.Label
        ConfidenceSpinner               matlab.ui.control.Spinner
        ConfidenceSpinnerLabel          matlab.ui.control.Label
        NumberofsamplesSpinner          matlab.ui.control.Spinner
        NumberofsamplesSpinnerLabel     matlab.ui.control.Label
        FeatureMatchDropDown            matlab.ui.control.DropDown
        FeatureMatchDropDownLabel       matlab.ui.control.Label
        StereoVisionEpipolarLinesLabel  matlab.ui.control.Label
        LoadLeftImageButton             matlab.ui.control.Button
        LoadRightImageButton            matlab.ui.control.Button
        EpipolarLinesRightButton        matlab.ui.control.Button
        EpipolarLinesLeftButton         matlab.ui.control.Button
        RANSACLabel_2                   matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        LoadTemplateButton              matlab.ui.control.StateButton
        LoadImageButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage
        ProcessedImage
        TemplateImage
        lmFilters
        WorkingImage
        currentROI
        LeftImage
        RightImage
    end
    
    methods (Access = private)
                       
                function F = makeLMfilters(app)
                SUP = 49;
                SCALEX = sqrt(2).^(1:3);
                NORIENT = 6;
        
                NROTINV = 12;
                NBAR = length(SCALEX)*NORIENT;
                NEDGE = length(SCALEX)*NORIENT;
                NF = NBAR + NEDGE + NROTINV;
                F = zeros(SUP, SUP, NF);
                hsup = (SUP - 1)/2;
                [x, y] = meshgrid(-hsup:hsup, hsup:-1:-hsup);
                orgpts = [x(:) y(:)]';
        
                count = 1;
                for scale = 1:length(SCALEX)
                    for orient = 0:NORIENT-1
                        angle = pi * orient / NORIENT;
                        c = cos(angle); s = sin(angle);
                        rotpts = [c -s; s c] * orgpts;
                        F(:,:,count) = app.makefilter(SCALEX(scale), 0, 1, rotpts, SUP);
                        F(:,:,count + NEDGE) = app.makefilter(SCALEX(scale), 0, 2, rotpts, SUP);
                        count = count + 1;
                    end
                end
        
                count = NBAR + NEDGE + 1;
                SCALES = sqrt(2).^(1:4);
                for i = 1:length(SCALES)
                    F(:,:,count)   = app.normalise(fspecial('gaussian', SUP, SCALES(i)));
                    F(:,:,count+1) = app.normalise(fspecial('log', SUP, SCALES(i)));
                    F(:,:,count+2) = app.normalise(fspecial('log', SUP, 3*SCALES(i)));
                    count = count + 3;
                end
            end
        
            function f = makefilter(app, scale, phasex, phasey, pts, sup)
                gx = app.gauss1d(3*scale, 0, pts(1,:), phasex);
                gy = app.gauss1d(scale, 0, pts(2,:), phasey);
                f = app.normalise(reshape(gx .* gy, sup, sup));
            end
        
            function g = gauss1d(~, sigma, mean, x, ord)
                x = x - mean;
                variance = sigma^2;
                denom = 2 * variance;
                g = exp(-x.^2 / denom) / sqrt(pi * denom);
                switch ord
                    case 1
                        g = -g .* (x / variance);
                    case 2
                        g = g .* ((x.^2 - variance) / variance^2);
                end
            end
        
            function f = normalise(~, f)
                f = f - mean(f(:));
                f = f / sum(abs(f(:)));
            end
            

            % harris
            function ProcessedImage = harrisCornerDetector(app, I)
            
            if size(I, 3) == 3
                I = rgb2gray(I);
            end
            I = im2single(I); 

            corners = detectHarrisFeatures(I);

            imshow(I, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Harris Corner Detection', 'FontSize', 10);
            
            hold(app.UIAxes2, 'on'); 
            
            if corners.Count > 0
                strongest_corners = selectStrongest(corners, min(50, corners.Count));
                
                points_locations = strongest_corners.Location;
                
                plot(app.UIAxes2, ...
                     points_locations(:, 1), ... 
                     points_locations(:, 2), ... 
                     'Color', 'r', 'Marker', 'o', 'MarkerSize', 5, 'LineStyle', 'none'); 
                
                title(app.UIAxes, ['Harris Corners Detected: ' num2str(strongest_corners.Count)], 'FontSize', 10);
            end
            
            hold(app.UIAxes2, 'off'); 
            
            ProcessedImage = I; 
        end
            
            function J = DoGFilter(app, I)
            
            if size(I, 3) == 3
                I = rgb2gray(I);
            end
            I_double = double(I); 

            gaussian1 = fspecial('Gaussian', 21, 15);
            gaussian2 = fspecial('Gaussian', 21, 20);
            
            dog = gaussian1 - gaussian2;
            
            J = conv2(I_double, dog, 'same');
            imshow(J, [], 'Parent', app.UIAxes);
            title(app.UIAxes2, 'Difference of Gaussian (DoG) Filter', 'FontSize', 10);
            end

            function applySingleDoG(app)

                I = app.WorkingImage;
            
                if size(I,3)==3
                    I = rgb2gray(I);
                end
                I = im2double(I);
            
                sigma = app.sigma_DoGLoGSlider.Value;
                if sigma <= 0
                    uialert(app.UIFigure,'Sigma must be > 0','Invalid Value');
                    return;
                end
                k = app.ScaleFactorSlider.Value;
            
                G1 = imgaussfilt(I, sigma);
                G2 = imgaussfilt(I, k * sigma);
            
                DoG = G1 - G2;
            
                imshow(DoG, [], 'Parent', app.UIAxes2);
                title(app.UIAxes2, ...
                    sprintf('Single-Scale DoG (σ = %.2f , kσ = %.2f)', sigma, k*sigma));
            
            end
            function applyMultiScaleDoG(app)

                I = app.WorkingImage;
            
                if size(I,3)==3
                    I = rgb2gray(I);
                end
                I = im2double(I);
            
                sigma0 = app.sigma_Slider.Value;
                k = app.ScaleFactorSlider.Value;
            
                sigmas = sigma0 * k.^(0:2);  
            
                figure('Name','Multi-Scale DoG','NumberTitle','off');
            
                for i = 1:length(sigmas)-1
                    G1 = imgaussfilt(I, sigmas(i));
                    G2 = imgaussfilt(I, sigmas(i+1));
                    DoG = G1 - G2;
            
                    subplot(1, length(sigmas)-1, i);
                    imshow(DoG, []);
                    title(sprintf('σ=%.2f → %.2f', sigmas(i), sigmas(i+1)));
                end

            end

        
            function [center, R] = calculateCircle(app, pts)
                x = pts(:,1); y = pts(:,2);
                A = [x(2)-x(1), y(2)-y(1); x(3)-x(2), y(3)-y(2)];
                B = [x(2)^2+y(2)^2-x(1)^2-y(1)^2; x(3)^2+y(3)^2-x(2)^2-y(2)^2]/2;
                
                if abs(det(A)) < 1e-5
                    center = [nan, nan]; R = nan;
                    return;
                end
                
                center = (A\B)';
                R = sqrt((x(1)-center(1))^2 + (y(1)-center(2))^2);
            end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ResetButton
        function ResetButtonPushed3(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                return;
            end
            %imshow(app.OriginalImage, 'Parent', app.UIAxes);
            %app.ProcessedImage = app.OriginalImage;
            app.WorkingImage = app.OriginalImage;
            
            if ~isempty(app.currentROI) && isvalid(app.currentROI)
                delete(app.currentROI);
            end
            app.currentROI = [];
            
            imshow(app.OriginalImage, 'Parent', app.UIAxes); 
            cla(app.UIAxes); 
            cla(app.UIAxes3);
        end

        % Callback function
        function ImageClicked(app, event)
            
        end

        % Callback function
        function ProcessButtonPushed2(app, event)
            
        end

        % Value changed function: histogramequalizationCheckBox
        function histogramequalizationCheckBoxValueChanged(app, event)
            if isempty(app.WorkingImage)
                return;
            end
            I= app.WorkingImage;
            if app.histogramequalizationCheckBox.Value
                if size(I,3)==1
                    eq = adapthisteq(I);
                    imshow(eq,'Parent',app.UIAxes);
                    title(app.UIAxes,'Histogram Equalized');
                else 
                    HSV = rgb2hsv(I);
                    V = HSV(:, :, 3);
                    V_eq = adapthisteq(V);
                    HSV_mod = HSV;
                    HSV_mod(:, :, 3) = V_eq;
                    RGB_eq = hsv2rgb(HSV_mod);
                    imshow(RGB_eq, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Histogram Equalized ');
                end 
            else
                app.WorkingImage = app.OriginalImage;
                imshow(app.OriginalImage,'Parent',app.UIAxes);
                title(app.UIAxes,'Original Image');
            end
            
        end

        % Value changed function: imagesharpeningDropDown
        function imagesharpeningDropDownValueChanged(app, event)

            if isempty(app.WorkingImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                app.imagesharpeningDropDown.Value = 'Non';
                return;
            end
            
            I = app.WorkingImage;
            if size(I, 3) == 3 
                I = rgb2gray(I);
            end
            choice = app.imagesharpeningDropDown.Value;
            A = 3.5; % boost factor
            
            switch choice
                case 'Non'
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original Image');
                    app.WorkingImage=I;
            
                case 'Laplacian filter - 1st'
                    Lp_1 = [0 -1 0; -1 4 -1; 0 -1 0]; 
                    J_14 = imfilter(double(I), Lp_1);
                    J_14 = im2uint8(I + J_14);
                    imshow(J_14,[], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Laplacian filter - 1st');
                    app.WorkingImage=J_14;
            
                case 'Laplacian filter - 2nd'
                    Lp_2 = [-1 -1 -1; -1 8 -1; -1 -1 -1];
                    J_15 = imfilter(double(I), Lp_2);
                    J_15 = im2uint8(I + J_15);
                    imshow(J_15,[], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Laplacian filter - 2nd');
                    app.WorkingImage=J_15;
            
                case 'Boosted Laplacian filter - 1st'
                    Lp_1_bst = [0 -1 0; -1 4+A -1; 0 -1 0];
                    J_16 = imfilter(double(I), Lp_1_bst);
                    J_16 = im2uint8(I + J_16);
                    imshow(J_16,[], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Boosted Laplacian filter - 1st');
                    app.WorkingImage=J_16;
            
                case 'Boosted Laplacian filter - 2nd'
                    Lp_2_bst = [-1 -1 -1; -1 8+A -1; -1 -1 -1];
                    J_17 = imfilter(double(I), Lp_2_bst);
                    J_17 = im2uint8(I + J_17);
                    imshow(J_17, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Boosted Laplacian filter - 2nd');
                    app.WorkingImage=J_17;
            
                case 'Horizontal Sobel filter'
                    Sb_x = [-1 -2 -1; 
                            0 0 0; 
                            1 2 1];
                    J_18 = imfilter(double(I), Sb_x);
                    J_18 = im2uint8(I + J_18);
                    imshow(J_18, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Horizontal Sobel filter');
                    app.WorkingImage=J_18;
            
                case 'Vertical Sobel filter'
                    Sb_y = [-1 0 1; 
                            -2 0 2; 
                            -1 0 1];
                    J_19 = imfilter(double(I), Sb_y);
                    J_19 = im2uint8(I + J_19);
                    imshow(J_19, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Vertical Sobel filter');
                    app.WorkingImage=J_19;
            
                case 'Horizontal Prewitt filter'
                    Pw_x = [-1 -1 -1; 0 0 0; 1 1 1];
                    J_20 = imfilter(double(I), Pw_x);
                    J_20 = im2uint8(I + J_20);
                    imshow(J_20, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Horizontal Prewitt filter');
                    app.WorkingImage=J_20;
            
                case 'Vertical Prewitt filter'
                    Pw_y = [-1 0 1; -1 0 1; -1 0 1];
                    J_21 = imfilter(double(I), Pw_y);
                    J_21 = im2uint8(I + J_21);
                    imshow(J_21, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Vertical Prewitt filter');
                    app.WorkingImage=J_21;
            end            
        end

        % Value changed function: imagesharpeningDropDown_2
        function imagesharpeningDropDown_2ValueChanged(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                app.imagesharpeningDropDown_2.Value = 'Non';
                return;
            end
        
            I = app.WorkingImage;
            if size(I, 3) == 3
                I = rgb2gray(I);
            end
            choice = app.imagesharpeningDropDown_2.Value;
            FT = fftshift(fft2(I));
            [m, n] = size(I);
            
            [X, Y] = meshgrid(1:n, 1:m);
            X = X - (n + 1)/2;
            Y = Y - (m + 1)/2;
            
            D = sqrt(X.^2 + Y.^2);
            switch choice
                case 'Non '
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original Image');
                    app.WorkingImage=I;
            
                case 'Ideal'
                    IHPF=D > 60; 
                    J = ifft2(ifftshift(FT .* IHPF));
                    imshow(abs(J), [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Ideal Filter');
                    app.WorkingImage=abs(J);
            
                case 'Gaussian high-pass filtering'
                    D0 = 30;
                    GHPF = 1-exp(-D.^2 / (2 * D0^2));
                    J = ifft2(ifftshift(FT .* GHPF));
                    imshow(abs(J), [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Gaussian HPF');
                    app.WorkingImage=abs(J);

            
                case 'Butterworth'
                    cutoff = 60;
                    n = 1; 
                    BHPF = 1 ./ (1 + (cutoff./ D).^(2*n));
                    J = ifft2(ifftshift(FT .* BHPF));
                    imshow(abs(J), [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Butterworth HPF');
                    app.WorkingImage=abs(J);

            end
        end

        % Value changed function: imagesmoothingDropDown
        function imagesmoothingDropDownValueChanged(app, event)
           if isempty(app.WorkingImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                app.imagesmoothingDropDown.Value = 'Non';
                return;
            end
                
            I = app.WorkingImage;
            choice = app.imagesmoothingDropDown.Value;
        
            switch choice
                case 'Non'
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original Image');
                    app.WorkingImage=I;

        
                case 'Box filter - size 3'
                    H_box_3 = (1/9).*[1 1 1; 1 1 1; 1 1 1]; 
                    J_2 = imfilter(double(I),H_box_3);
                    imshow(J_2, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Box filter - size 3');
                    app.WorkingImage=J_2;


                case 'Box filter - size 5'
                    H_box_5 = (1/25).* ones(5,5);
                    J_3 = imfilter(double(I),H_box_5);
                    imshow(J_3, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Box filter - size 5');
                    app.WorkingImage=J_3;

                case 'Box filter - size 7'
                    H_box_7 = (1/49).* ones(7,7);
                    J_4 = imfilter(double(I),H_box_7);
                    imshow(J_4, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Box filter - size 7');
                    app.WorkingImage=J_4;
                
                case 'Box filter - size 9'
                    H_box_9 = (1/81).* ones(9,9);
                    J_5 = imfilter(double(I),H_box_9);
                    imshow(J_5, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Box filter - size 9');
                    app.WorkingImage=J_5;
                
                case 'Average filter - size 3'
                    H_avg_3 = (1/16).*[1 2 1; 2 4 2; 1 2 1]; 
                    J_6 = imfilter(double(I),H_avg_3);
                    imshow(J_6, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Average filter - size 3');
                    app.WorkingImage=J_6;
        
                case 'Average filter - size 5'  
                    H_avg_5 = (1/65).*[1 2 2 2 1; 
                                       1 2 4 2 1;
                                       2 4 8 4 2;
                                       1 2 4 2 1;
                                       1 2 2 2 1]; 
                    J_7 = imfilter(double(I),H_avg_5);
                    imshow(J_7, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Average filter - size 5');
                    app.WorkingImage=J_7;

                
                case 'Average filter - size 7'  
                    H_avg_7 = (1/128).*[1 1 1 2 1 1 1; 
                                        1 1 2 4 1 1 2;
                                        1 2 4 8 4 2 1;
                                        2 4 8 16 8 4 2;
                                        1 2 4 8 4 2 1;
                                        1 1 2 4 1 1 2;
                                        1 1 1 2 1 1 1]; 
                    J_8 = imfilter(double(I),H_avg_7);
                    imshow(J_8 ,[], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Average filter - size 7');
                    app.WorkingImage=J_8;
               
                case 'Average filter - size 9'  
                    H_avg_9 = (1/280).*[1 1 1 1 2 1 1 1 1;
                                        1 1 1 2 4 2 1 1 1; 
                                        1 1 2 4 8 4 2 1 1;
                                        1 2 4 8 16 8 4 2 1;
                                        2 4 8 16 32 16 8 4 2;
                                        1 2 4 8 16 8 4 2 1;
                                        1 1 2 4 8 4 2 1 1;
                                        1 1 1 2 4 2 1 1 1;
                                        1 1 1 1 2 1 1 1 1]; 
                    J_9 = imfilter(double(I),H_avg_9);
                    imshow(J_9, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Average filter - size 9');
                    app.WorkingImage=J_9;
                
                case 'Median filter - size 3'
                    J_10 = medfilt3(double(I), [3 3 1]);
                    imshow(J_10, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Median filter - size 3');
                    app.WorkingImage=J_10;

                case 'Median filter - size 5'
                    J_11 = medfilt3(double(I), [5 5 1]);
                    imshow(J_11, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Median filter - size 5');
                    app.WorkingImage=J_11;

                case 'Median filter - size 7'
                    J_12 = medfilt3(double(I), [7 7 1]);
                    imshow(J_12, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Median filter - size 7');
                    app.WorkingImage=J_12;

                case 'Median filter - size 9'
                    J_13 = medfilt3(double(I), [9 9 1]);
                    imshow(J_13, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Median filter - size 9');
                    app.WorkingImage=J_13;

            end 
        end

        % Value changed function: imagesmoothingDropDown_2
        function imagesmoothingDropDown_2ValueChanged(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                app.imagesmoothingDropDown_2.Value = 'Non';
                return;
            end
        
            I = double(app.WorkingImage);
            if size(I, 3) == 3
                I = rgb2gray(I);
            end
            choice = app.imagesmoothingDropDown_2.Value;
            FT = fftshift(fft2(I));
            [m, n] = size(I);
            
            [X, Y] = meshgrid(1:n, 1:m);
            X = X - (n + 1)/2;
            Y = Y - (m + 1)/2;
            
            D = sqrt(X.^2 + Y.^2);
            switch choice
                case 'Non '
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original Image');
                    app.WorkingImage=I;
            
                case 'Ideal'
                    ILPF =D < 60;
                    J = ifft2(ifftshift(FT .* ILPF));
                    imshow(abs(J), [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Ideal Filter');
                    app.WorkingImage=abs(J);

            
                case 'Gaussian low-pass filtering'
                    D0 = 30;
                    GLPF = exp(-D.^2 / (2 * D0^2));
                    J = ifft2(ifftshift(FT .* GLPF));
                    imshow(abs(J), [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Gaussian LPF');
                    app.WorkingImage=abs(J);

            
                case 'Butterworth'
                    cutoff = 60;
                    n = 1; 
                    BLPF = 1 ./ (1 + (D ./ cutoff).^(2*n));
                    J = ifft2(ifftshift(FT .* BLPF));
                    imshow(abs(J), [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Butterworth LPF');
                    app.WorkingImage=abs(J);

            end

            
        end

        % Value changed function: IntensityBrightnessSlider
        function IntensityBrightnessSliderValueChanged(app, event)
            if isempty(app.WorkingImage)
                    return;
                end
                value = app.IntensityBrightnessSlider.Value;
                img = im2double(app.WorkingImage);
                bright = img + value/200;  
                bright = max(min(bright,1),0); 
                app.WorkingImage = bright;
                imshow(bright,'Parent',app.UIAxes);
                title(app.UIAxes,'Brightness Adjusted');
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.tif'});
            if isequal(file,0)
                return;
            end
            I = im2double(imread(fullfile(path,file)));
            app.OriginalImage = I;
            app.WorkingImage = I;
            imshow(I, 'Parent', app.UIAxes2);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: HSIDropDown
        function HSIDropDownValueChanged(app, event)
             if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                app.HSIDropDown.Value = 'Non';
                return;
            end
                
            J = app.OriginalImage;
            
            if size(J, 3) == 1
                uialert(app.UIFigure, 'The image is grayscale, cannot convert to HSI.', 'Error');
                app.HSIDropDown.Value = 'Non';
                imshow(J, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Grayscale Image');
                return;
            end
            
            HSI=rgb2hsv(J);
            choice = app.HSIDropDown.Value;
        
            switch choice
                case 'Non'
                    imshow(J, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original Image');
                case 'Full'
                    imshow(HSI, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'HSI Image');
        
                case 'H'
                    H = HSI(:,:,1);
                    imshow(H, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Hue Channel');

                case 'S'
                    S = HSI(:,:,2);
                    imshow(S, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Saturation Channel');

                case 'I'
                    I = HSI(:,:,3);
                    imshow(I, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Intensity Channel');
            end 
        end

        % Value changed function: RGBDropDown
        function RGBDropDownValueChanged(app, event)
             if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                app.RGBDropDown.Value = 'Non';
                return;
            end
                
            J = app.OriginalImage;
       
            if size(J, 3) == 1
                uialert(app.UIFigure, 'The image is grayscale, cannot convert to HSI.', 'Error');
                app.HSIDropDown.Value = 'Non';
                imshow(J, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Grayscale Image');
                return;
            end

            choice = app.RGBDropDown.Value;
        
            switch choice
                case 'Non'
                    imshow(J, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original Image');
                case 'Full'
                    imshow(J, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'RGB Image');
        
                case 'R'
                    R = J(:,:,1);
                    imshow(R, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Red Channel');

                case 'G'
                    G = J(:,:,2);
                    imshow(G, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Green Channel');

                case 'B'
                    B = J(:,:,3);
                    imshow(B, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Blue Channel');
            end 
        end

        % Value changed function: YCbCrDropDown
        function YCbCrDropDownValueChanged(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                app.YCbCrDropDown.Value = 'Non';
                return;
            end
            
            J = im2double(app.OriginalImage);


            if size(J, 3) == 1
                uialert(app.UIFigure, 'The image is grayscale, cannot convert to HSI.', 'Error');
                app.HSIDropDown.Value = 'Non';
                imshow(J, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Grayscale Image');
                return;
            end

            YCbCr = rgb2ycbcr(J);
            choice = app.YCbCrDropDown.Value;
            
            switch choice
                case 'Non'
                    imshow(J, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original Image');
                case 'Full'
                    imshow(YCbCr, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'YCbCr Image');
            
                case 'Y'
                    Y = YCbCr(:,:,1);
                    imshow(Y, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Luminance (Y)');
            
                case 'Cb'
                    Cb = YCbCr(:,:,2);
                    imshow(Cb, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Blue-difference (Cb)');
            
                case 'Cr'
                    Cr = YCbCr(:,:,3);
                    imshow(Cr, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Red-difference (Cr)');
            end

        end

        % Value changed function: LabDropDown
        function LabDropDownValueChanged(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                app.LabDropDown.Value = 'Non';
                return;
            end
            
            J = im2double(app.OriginalImage);

       
            if size(J, 3) == 1
                uialert(app.UIFigure, 'The image is grayscale, cannot convert to HSI.', 'Error');
                app.HSIDropDown.Value = 'Non';
                imshow(J, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Grayscale Image');
                return;
            end

            Lab = rgb2lab(J);
            choice = app.LabDropDown.Value;
            
            switch choice
                case 'Non'
                    imshow(J, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original Image');
                case 'Full'
                    imshow(Lab, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'L*a*b* Image');
            
                case 'L'
                    L = Lab(:,:,1);
                    imshow(L, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'L Channel');
            
                case 'A'
                    A = Lab(:,:,2);
                    imshow(A, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'A Channel');
            
                case 'B'
                    B = Lab(:,:,3);
                    imshow(B, [], 'Parent', app.UIAxes);
                    title(app.UIAxes, 'B Channel');
            end

            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Value changed function: GaussianSlider
        function GaussianSliderValueChanged(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                return;
            end
        
            I = app.WorkingImage;
        
            if size(I, 3) == 3
                I = rgb2gray(I);
            end
        
            I = im2uint8(I);  
        
            I_1 = impyramid(I, 'reduce');
            I_2 = impyramid(I_1, 'reduce');
            I_3 = impyramid(I_2, 'reduce');
        
            I1 = impyramid(I, 'expand');
            I2 = impyramid(I1, 'expand');
            I3 = impyramid(I2, 'expand');
        
            choice = app.GaussianSlider.Value;
        
            switch choice
                case -3
                    imshow(I_3, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Reduce_3');
        
                case -2
                    imshow(I_2, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Reduce_2');
        
                case -1
                    imshow(I_1, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Reduce_1');
        
                case 0
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original');
        
                case 1
                    imshow(I1, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Expand_1');
        
                case 2
                    imshow(I2, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Expand_2');
        
                case 3
                    imshow(I3, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Expand_3');
            end

            
        end

        % Value changed function: LoadTemplateButton
        function LoadTemplateButtonValueChanged(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.tif'});
            if isequal(file,0)
                return;
            end
            app.TemplateImage = im2double(imread(fullfile(path,file)));
            imshow(app.TemplateImage, 'Parent', app.UIAxes3);
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Please load the main image first!', 'Error');
                return;
            end
        
            if isempty(app.TemplateImage)
                uialert(app.UIFigure, 'Please load the template image first!', 'Error');
                return;
            end
        
            I = app.WorkingImage;
            T = app.TemplateImage;
        
            if size(I,3) == 3, I = rgb2gray(I); end
            if size(T,3) == 3, T = rgb2gray(T); end
        
            I = im2double(I);
            T = im2double(T);
        
            [h, w] = size(T);
            [H, W] = size(I);
        
            choice = app.DropDown.Value;

            switch choice
                case 'Non'
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original Image');
                    cla(app.UIAxes3);                     
                case 'NCC' 
                    c = normxcorr2(T, I);
                    
                    [ypeak, xpeak] = find(c == max(c(:)));
                    
                    yoff = ypeak - h;
                    xoff = xpeak - w;
        
                    
                    mapShow = c(h:end-h, w:end-w); 
                    imshow(mapShow, [], 'Parent', app.UIAxes3);
                    title(app.UIAxes3, 'NCC Map');
                    colormap(app.UIAxes3, 'jet'); 
        
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'NCC Best Match');
                    hold(app.UIAxes, 'on');
                    rectangle(app.UIAxes, 'Position', [xoff+1, yoff+1, w, h], ...
                        'EdgeColor', 'r', 'LineWidth', 2);
                    hold(app.UIAxes, 'off');
                    app.WorkingImage=I;
        
                case 'ZNCC'
                    
                    Tm = T - mean(T(:)); 
                    Tstd = std(Tm(:));
                    
                    Z = -inf(H, W); 
                    
                    for x = 1:H-h
                        for y = 1:W-w
                            block = I(x:x+h-1, y:y+w-1);
                            Bm = block - mean(block(:));
                            Bstd = std(Bm(:));
                            if Bstd == 0
                                Z(x,y) = -1; 
                            else
                                Z(x,y) = sum(sum(Tm .* Bm)) / (Tstd * Bstd);
                            end
                        end
                    end
                    
                    [~, idx] = max(Z(:));
                    [bestRow, bestCol] = ind2sub(size(Z), idx);
        
                    imshow(Z, [], 'Parent', app.UIAxes3);
                    title(app.UIAxes3, 'ZNCC Map');
                    
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'ZNCC Best Match');
                    hold(app.UIAxes, 'on');
                    rectangle(app.UIAxes, 'Position', [bestCol, bestRow, w, h], ...
                        'EdgeColor', 'b', 'LineWidth', 2);
                    hold(app.UIAxes, 'off');
                    app.WorkingImage=I;
        
                case 'SSD'
                    
                    SSD_Map = inf(H, W); 
                    for x = 1:H-h
                        for y = 1:W-w
                            patch = I(x:x+h-1, y:y+w-1);
                            diff = (patch - T).^2;
                            SSD_Map(x,y) = sum(diff(:));
                        end
                    end
        
                    [~, idx] = min(SSD_Map(:));
                    [bestRow, bestCol] = ind2sub(size(SSD_Map), idx);
        
                    
                    SSD_Show = max(SSD_Map(:)) - SSD_Map; 
                    imshow(SSD_Show, [], 'Parent', app.UIAxes3);
                    title(app.UIAxes3, 'SSD Map (Inverted)');
        
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'SSD Best Match');
                    hold(app.UIAxes, 'on');
                    rectangle(app.UIAxes, 'Position', [bestCol, bestRow, w, h], ...
                        'EdgeColor', 'g', 'LineWidth', 2);
                    hold(app.UIAxes, 'off');
                    app.WorkingImage=I;
            end
            
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Callback function
        function DropDown2ValueChanged(app, event)
           
        end

        % Value changed function: LMDropDown
        function LMDropDownValueChanged(app, event)
            value = app.LMDropDown.Value;
            
            switch value
                case 'Spot'
                    app.OriantationDropDown.Enable = 'off';
                    app.ScaleDropDown.Items = {'1', '2', '3', '4'};
                    
                case {'Bar', 'Edge'}
                    app.OriantationDropDown.Enable = 'on';
                    app.ScaleDropDown.Items = {'1', '2', '3'};
                    if strcmp(app.ScaleDropDown.Value, '4')
                         app.ScaleDropDown.Value = '1';
                    end

                otherwise 
                    app.OriantationDropDown.Enable = 'on';
                    app.ScaleDropDown.Items = {'1', '2', '3', '4'};
            end

        end

        % Value changed function: LaplacianSlider
        function LaplacianSliderValueChanged(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure,'Please load an image first!','Error');
                return;
            end
            
            I = app.WorkingImage;
            
            if size(I, 3) == 3
                I = rgb2gray(I);
            end
            
            I = im2uint8(I);
            
            G0 = I;
            G1 = impyramid(G0, 'reduce');
            G2 = impyramid(G1, 'reduce');
            G3 = impyramid(G2, 'reduce');
            
            E1 = impyramid(G1, 'expand');
            E1 = imresize(E1, size(G0));
            L1 = imsubtract(G0, E1);
            
            E2 = impyramid(G2, 'expand');
            E2 = imresize(E2, size(G1));
            L2 = imsubtract(G1, E2);
            
            E3 = impyramid(G3, 'expand');
            E3 = imresize(E3, size(G2));
            L3 = imsubtract(G2, E3);

            choice = app.LaplacianSlider.Value;
            
            switch choice
                case -3
                    imshow(L3, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Laplacian Level 3');
            
                case -2
                    imshow(L2, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Laplacian Level 2');
            
                case -1
                    imshow(L1, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Laplacian Level 1');
            
                case 0
                    imshow(I, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Original');
            
                case 1
                    imshow(G1, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Gaussian Reduce 1');
            
                case 2
                    imshow(G2, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Gaussian Reduce 2');
            
                case 3
                    imshow(G3, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Gaussian Reduce 3');

            end
        end

        % Callback function
        function OriantationDropDownValueChanged(app, event)
          
        end

        % Callback function
        function ScaleDropDownValueChanged(app, event)
            
        end

        % Button pushed function: DoneFBButton
        function DoneFBButtonPushed(app, event)
           
            if isempty(app.lmFilters)
                app.lmFilters = makeLMfilters(app);
            end
        
          
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end
        
            I = app.WorkingImage;
            if size(I,3) == 3
                I = rgb2gray(I);
            end
            I = im2double(I);
        
            imshow(I, 'Parent', app.UIAxes2);
            title(app.UIAxes2, 'Original Image');
        
            lmType = app.LMDropDown.Value;   
        
            if strcmp(lmType,'Non')
                cla(app.UIAxes); 
                cla(app.UIAxes3);
                return;
            end
        
            orientStr = app.OriantationDropDown.Value;
            angleVal = str2double(orientStr);
        
            if isnan(angleVal)
                orientIdx = 0;
            else
                orientIdx = angleVal / 30;
            end
        
            orientIdx = mod(orientIdx, 6);
        
            scaleVal = str2double(app.ScaleDropDown.Value);
            scaleVal = max(1, min(scaleVal, 4));  
        
           
            NORIENT = 6;
            NBAR  = 18;   
            NEDGE = 18;   
        
            filterIdx = 1;
        
            switch lmType
        
                case 'Bar'
                    s = min(scaleVal,3);
                    filterIdx = (s-1)*NORIENT + orientIdx + 1;
        
                case 'Edge'
                    s = min(scaleVal,3);
                    filterIdx = NBAR + (s-1)*NORIENT + orientIdx + 1;
        
                case 'Spot'
                    filterIdx = NBAR + NEDGE + (scaleVal-1)*3 + 2;
        
            end
        
            filterIdx = max(1, min(filterIdx, size(app.lmFilters,3)));
        
           
            selectedFilter = app.lmFilters(:,:,filterIdx);
        
            response = imfilter(I, selectedFilter, 'same', 'replicate');
        
            combined = I + 2*response;
            imshow(combined, [], 'Parent', app.UIAxes);
            title(app.UIAxes, sprintf('Filtered (%s)', lmType));
        
            energyMap = mat2gray(abs(response));
            imshow(energyMap, [], 'Parent', app.UIAxes3);
            title(app.UIAxes3, 'Energy Map');
        
           
            figure('Name','LM Filters Bank','NumberTitle','off');
            tiled = tiledlayout(6,8,'TileSpacing','compact','Padding','compact');
        
            for k = 1:size(app.lmFilters,3)
                nexttile(tiled);
                imagesc(app.lmFilters(:,:,k));
                axis off;
                colormap gray;
                title(sprintf('F%d',k),'FontSize',7);
            end

            
            Igray = I;  
            
            numF = size(app.lmFilters,3);
            allResponses = zeros(size(Igray,1), size(Igray,2), numF);
            
            for k = 1:numF
                allResponses(:,:,k) = imfilter(Igray, app.lmFilters(:,:,k), 'same', 'replicate');
            end
            
            
            figure('Name','All Filtered Outputs','NumberTitle','off');
            t = tiledlayout(6,8,'TileSpacing','compact','Padding','compact');
            
            for k = 1:numF
                nexttile(t);
                imshow(allResponses(:,:,k),[]);
                title(sprintf('F%d', k), 'FontSize', 7);
            end
            
            title(t, 'LM Filter Bank Responses (48 Filters)', 'FontSize', 14);


        end

        % Value changed function: sigmaSlider
        function sigmaSliderValueChanged(app, event)
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
        end

        % Button pushed function: DoneCannyButton
        function DoneCannyButtonPushed(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end
        
            I = app.WorkingImage;
            if size(I, 3) == 3
                I = rgb2gray(I);
            end
            
            sig = app.sigmaSlider.Value; 
            
            thresh = app.ThresholdSlider.Value;
            
            try
                BW = edge(I, 'Canny', thresh, sig);
               
                imshow(I, 'Parent', app.UIAxes2);
                title(app.UIAxes2, 'Original Image');
                
                imshow(BW, 'Parent', app.UIAxes3);
                title(app.UIAxes3, 'Binary Edges');

                OverlayImg = I;
                OverlayImg(BW) = 1; 
                
                imshow(OverlayImg, 'Parent', app.UIAxes);
                title(app.UIAxes, sprintf('Detected Edges (Sigma: %.1f)', sig));
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Error in Edge Detection');
            end
        end

        % Button pushed function: DoneHarrisButton
        function DoneHarrisButtonPushed(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end
        
            I = app.WorkingImage;
            if size(I, 3) == 3
                I = rgb2gray(I);
            end
            
            minStrength = app.MinCornerStrengthSpinner.Value;
            nSize = app.NeighborhoodSizeSpinner.Value;
            numCorners = app.NumberofCornersSpinner.Value;
            
            if mod(nSize, 2) == 0
                nSize = nSize + 1; 
            end
            
            try
                corners = detectHarrisFeatures(I, ...
                    'MinQuality', minStrength, ...
                    'FilterSize', nSize);
                
                if corners.Count > numCorners
                    corners = corners.selectStrongest(numCorners);
                end
                
                imshow(I, 'Parent', app.UIAxes);
                hold(app.UIAxes, 'on'); 
                
                plot(app.UIAxes, corners.Location(:,1), corners.Location(:,2), 'g+');
                
                hold(app.UIAxes, 'off'); 
                title(app.UIAxes, sprintf('Harris Corners (Count: %d)', corners.Count));
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Error in Harris Detection');
            end

        end

        % Value changed function: MinCornerStrengthSpinner
        function MinCornerStrengthSpinnerValueChanged(app, event)
            value = app.MinCornerStrengthSpinner.Value;
            
        end

        % Value changed function: NeighborhoodSizeSpinner
        function NeighborhoodSizeSpinnerValueChanged(app, event)
            value = app.NeighborhoodSizeSpinner.Value;
            
        end

        % Value changed function: NumberofCornersSpinner
        function NumberofCornersSpinnerValueChanged(app, event)
            value = app.NumberofCornersSpinner.Value;
            
        end

        % Button pushed function: SelectSpecificButton
        function SelectSpecificButtonPushed(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end
            
            I = app.WorkingImage;
            if size(I, 3) == 3
                I = rgb2gray(I);
            end
            
            imshow(I, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Draw a rectangle, then Double-Click inside it');
            
            roi = drawrectangle(app.UIAxes);
            pause(0.1);
            
           
            pos = round(roi.Position); 
            
            delete(roi);
            
            try
                subImage = imcrop(I, pos);
            catch
                uialert(app.UIFigure, 'Invalid Region Selected', 'Error');
                return;
            end
            
            minStrength = app.MinCornerStrengthSpinner.Value;
            nSize = app.NeighborhoodSizeSpinner.Value;
            if mod(nSize, 2) == 0, nSize = nSize + 1; end
            numCorners = app.NumberofCornersSpinner.Value;
            
            try
                corners = detectHarrisFeatures(subImage, ...
                    'MinQuality', minStrength, ...
                    'FilterSize', nSize);
                
                if corners.Count > numCorners
                    corners = corners.selectStrongest(numCorners);
                end
                
                globalPoints = corners.Location;
                if ~isempty(globalPoints)
                    globalPoints(:,1) = globalPoints(:,1) + pos(1); 
                    globalPoints(:,2) = globalPoints(:,2) + pos(2); 
                end
                
                imshow(I, 'Parent', app.UIAxes);
                hold(app.UIAxes, 'on');
                
                rectangle(app.UIAxes, 'Position', pos, 'EdgeColor', 'r', 'LineWidth', 2);
                
                if ~isempty(globalPoints)
                    plot(app.UIAxes, globalPoints(:,1), globalPoints(:,2), 'g+');
                end
                
                hold(app.UIAxes, 'off');
                title(app.UIAxes, sprintf('Harris in Region (%d corners)', corners.Count));
                
            catch ME
                uialert(app.UIFigure, ME.message, 'Error');
            end
        end

        % Button pushed function: DoneDoGLoGButton
        function DoneDoGLoGButtonPushed(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Load an image first!', 'Error');
                return;
            end
        
            I = app.WorkingImage;
            if size(I,3) == 3
                I = im2gray(I);
            end
            I = im2double(I);
        
            method     = app.TypeDropDown.Value;  
            sigma0     = app.sigma_DoGLoGSlider.Value;
            k          = app.ScaleFactorSlider.Value;
            numScales  = app.numberofscalesSpinner.Value;
        
            if numScales < 1
                numScales = 1;
            end
        
            responses = cell(1, numScales);
        
            if strcmp(method, 'LoG')
        
                for i = 1:numScales
                    sigma = sigma0 * k^(i-1);
                    h = fspecial('log', ceil(6*sigma), sigma);
                    responses{i} = imfilter(I, h, 'replicate') * sigma^2;
                end
        
                titleText = 'Laplacian of Gaussian (Multi-Scale)';
        
            elseif strcmp(method, 'DoG')
        
                for i = 1:numScales
                    s1 = sigma0 * k^(i-1);
                    s2 = s1 * k;
                    G1 = imgaussfilt(I, s1);
                    G2 = imgaussfilt(I, s2);
                    responses{i} = G2 - G1;
                end
        
                titleText = 'Difference of Gaussian (Multi-Scale)';
        
            else
                uialert(app.UIFigure, 'Select DoG or LoG', 'Input Error');
                return;
            end

            R = responses{end};

            imshow(R, [], 'Parent', app.UIAxes3);
            title(app.UIAxes3, titleText, 'FontSize', 10);

            if strcmp(method, 'LoG')
                edges = edge(I, 'log', [], sigma0);
            else
                edges = abs(R) > 0.03;   
            end

            I_uint8 = im2uint8(I);
            I_rgb   = repmat(I_uint8, [1 1 3]);
        
            overlay = I_rgb;
            overlay(:,:,1) = I_rgb(:,:,1) + uint8(edges)*255; 
        
            
            imshow(overlay, 'Parent', app.UIAxes);
            title(app.UIAxes, titleText, 'FontSize', 10);
                   

        end

        % Callback function
        function sigma_DoGSliderValueChanged(app, event)
            
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            
        end

        % Value changed function: sigma_DoGLoGSlider
        function sigma_DoGLoGSliderValueChanged2(app, event)
            value = app.sigma_DoGLoGSlider.Value;
            
        end

        % Value changed function: ScaleFactorSlider
        function ScaleFactorSliderValueChanged(app, event)
            value = app.ScaleFactorSlider.Value;
            
        end

        % Value changed function: numberofscalesSpinner
        function numberofscalesSpinnerValueChanged(app, event)
            value = app.numberofscalesSpinner.Value;
            
        end

        % Value changed function: VisualizationCheckBox
        function VisualizationCheckBoxValueChanged(app, event)
            value = app.VisualizationCheckBox.Value;
            
        end

        % Value changed function: CellSizeSpinner
        function CellSizeSpinnerValueChanged(app, event)
            value = app.CellSizeSpinner.Value;
            
        end

        % Value changed function: BinNumberSpinner
        function BinNumberSpinnerValueChanged(app, event)
            value = app.BinNumberSpinner.Value;
            
        end

        % Value changed function: BlockSizeSpinner
        function BlockSizeSpinnerValueChanged(app, event)
            value = app.BlockSizeSpinner.Value;
            
        end

        % Value changed function: NormalizationDropDown
        function NormalizationDropDownValueChanged(app, event)
            value = app.NormalizationDropDown.Value;
            
        end

        % Value changed function: GradientmethodDropDown
        function GradientmethodDropDownValueChanged(app, event)
            value = app.GradientmethodDropDown.Value;
            
        end

        % Button pushed function: DoneHoGButton
        function DoneHoGButtonPushed(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Load an image first!', 'Error');
                return;
            end
        
            I = app.WorkingImage;
        
            if size(I,3) == 3
                Igray = rgb2gray(I);
            else
                Igray = I;
            end
        
            Igray = im2single(Igray);
        
            % ===== Grayscale =====
            cla(app.UIAxes3);
            imshow(Igray, 'Parent', app.UIAxes3);
            title(app.UIAxes3, 'Grayscale Image');
        
            % ===== HoG parameters =====
            cellSize = [8 8];
            numBins  = 9;
        
            [~, hogVisualization] = extractHOGFeatures( ...
                Igray, ...
                'CellSize', cellSize, ...
                'NumBins', numBins);
        
            % ===== HoG over image =====
            cla(app.UIAxes);
            axes(app.UIAxes);          % ⭐ هذا السطر هو المفتاح
            imshow(Igray);
            title('Histogram of Oriented Gradients (HoG)');
            hold on;
        
            plot(hogVisualization);    % ⭐ بدون Parent
        
            hold off;


        end

        % Value changed function: NumPeaksSpinner
        function NumPeaksSpinnerValueChanged(app, event)
            value = app.NumPeaksSpinner.Value;
            
        end

        % Value changed function: FillGrapSpinner
        function FillGrapSpinnerValueChanged(app, event)
            value = app.FillGrapSpinner.Value;
            
        end

        % Value changed function: MinLengthSpinner
        function MinLengthSpinnerValueChanged(app, event)
            value = app.MinLengthSpinner.Value;
            
        end

        % Value changed function: MinRadiusSpinner
        function MinRadiusSpinnerValueChanged(app, event)
            value = app.MinRadiusSpinner.Value;
            
        end

        % Value changed function: MaxRadiusSpinner
        function MaxRadiusSpinnerValueChanged(app, event)
            value = app.MaxRadiusSpinner.Value;
            
        end

        % Value changed function: ThersholdSpinner
        function ThersholdSpinnerValueChanged(app, event)
            value = app.ThersholdSpinner.Value;
            
        end

        % Value changed function: PolarityDropDown
        function PolarityDropDownValueChanged(app, event)
            value = app.PolarityDropDown.Value;
            
        end

        % Button pushed function: DoneHoughCircleButton
        function DoneHoughCircleButtonPushed(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end
        
            rmin = app.MinRadiusSpinner.Value;     
            rmax = app.MaxRadiusSpinner.Value;     
            
            thresh = app.ThersholdSpinner.Value;
            if thresh > 1
                thresh = thresh / 100;
            end
            
            thresh = max(0, min(1, thresh));
        
            polar = app.PolarityDropDown.Value;      
        
            if strcmpi(polar, 'Light') || strcmp(polar, 'Non')
                polar = 'bright'; 
            else
                polar = 'dark';
            end
        
            I = app.WorkingImage;
            if size(I, 3) == 3, I = im2gray(I); end
        
        
            try
                [centers, radii] = imfindcircles(I, [rmin rmax], ...
                    'ObjectPolarity', polar, ...
                    'Sensitivity', thresh);
        
                cla(app.UIAxes);
                imshow(app.WorkingImage, 'Parent', app.UIAxes);
                
                if ~isempty(centers)
                    hold(app.UIAxes, 'on');
                    viscircles(app.UIAxes, centers, radii, 'EdgeColor', 'b', 'LineWidth', 1);
                    hold(app.UIAxes, 'off');
                    title(app.UIAxes, ['Circles Detected: ', num2str(length(radii))]);
                else
                    title(app.UIAxes, 'No circles detected. Try increasing Sensitivity.');
                end
                
            catch ME
                uialert(app.UIFigure, ['Error: ' ME.message], 'Processing Error');
            end


        end

        % Value changed function: ModelTypeDropDown
        function ModelTypeDropDownValueChanged(app, event)
            value = app.ModelTypeDropDown.Value;
            
        end

        % Value changed function: IterationsSpinner
        function IterationsSpinnerValueChanged(app, event)
            value = app.IterationsSpinner.Value;
            
        end

        % Value changed function: InlierThresholdSpinner
        function InlierThresholdSpinnerValueChanged(app, event)
            value = app.InlierThresholdSpinner.Value;
            
        end

        % Value changed function: MinimumInliersSpinner
        function MinimumInliersSpinnerValueChanged(app, event)
            value = app.MinimumInliersSpinner.Value;
            
        end

        % Value changed function: NoiseLevelSlider
        function NoiseLevelSliderValueChanged(app, event)
             value = app.NoiseLevelSlider.Value;

        end

        % Button pushed function: DoneRANSACButton
        function DoneRANSACButtonPushed(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end
        
            modelType = app.ModelTypeDropDown.Value; 
            iter = app.IterationsSpinner.Value;
            thresh = app.InlierThresholdSpinner.Value;
            minInliers = app.MinimumInliersSpinner.Value;
        
            I = getimage(app.UIAxes3); 
            if isempty(I)
                I = app.WorkingImage; 
            else

            end
            
            if size(I, 3) == 3, I = im2gray(I); end
            BW = edge(I, 'canny'); 
            [y, x] = find(BW);
            allPoints = [x, y];
        
            if isempty(allPoints)
                uialert(app.UIFigure, 'No edges detected. Adjust image or noise.', 'Warning');
                return;
            end
        
            cla(app.UIAxes3);
            imshow(app.WorkingImage, 'Parent', app.UIAxes3);
            hold(app.UIAxes3, 'on');
        
            if strcmp(modelType, 'Line')
                bestInliers = [];
                for i = 1:iter
                    if size(allPoints, 1) < 2, break; end
                    idx = randperm(size(allPoints, 1), 2);
                    p1 = allPoints(idx(1), :); p2 = allPoints(idx(2), :);
                    
                    % مسافة النقطة عن الخط
                    v = p2 - p1;
                    if norm(v) == 0, continue; end
                    dist = abs(v(1)*(p1(2)-allPoints(:,2)) - v(2)*(p1(1)-allPoints(:,1))) / norm(v);
                    inliers = find(dist < thresh);
                    
                    if length(inliers) > length(bestInliers)
                        bestInliers = inliers;
                    end
                end
                
                if length(bestInliers) >= minInliers
                    plot(app.UIAxes3, allPoints(bestInliers,1), allPoints(bestInliers,2), 'r.', 'MarkerSize', 8);
                    title(app.UIAxes3, ['Line Found: ', num2str(length(bestInliers)), ' inliers']);
                else
                    title(app.UIAxes3, 'RANSAC: No line matches the criteria.');
                end
        
            elseif strcmp(modelType, 'Circle')
                bestInliers = [];
                bestCenter = []; bestR = 0;
                
                for i = 1:iter
                    if size(allPoints, 1) < 3, break; end
                    idx = randperm(size(allPoints, 1), 3);
                    pts = allPoints(idx, :);
                    
                    % استدعاء الدالة المساعدة عبر app
                    [center, R] = app.calculateCircle(pts);
                    
                    if ~isnan(R) && R > 0
                        dist = abs(sqrt((allPoints(:,1)-center(1)).^2 + (allPoints(:,2)-center(2)).^2) - R);
                        inliers = find(dist < thresh);
                        
                        if length(inliers) > length(bestInliers)
                            bestInliers = inliers;
                            bestCenter = center; bestR = R;
                        end
                    end
                end
                
                if length(bestInliers) >= minInliers
                    viscircles(app.UIAxes3, bestCenter, bestR, 'EdgeColor', 'b', 'LineWidth', 1.5);
                    plot(app.UIAxes3, allPoints(bestInliers,1), allPoints(bestInliers,2), 'g.', 'MarkerSize', 5);
                    title(app.UIAxes3, ['Circle Found: R=', num2str(round(bestR))]);
                else
                    title(app.UIAxes3, 'RANSAC: No circle matches the criteria.');
                end
            end
            hold(app.UIAxes3, 'off');

        end

        % Value changed function: FeatureMatchDropDown
        function FeatureMatchDropDownValueChanged(app, event)
            value = app.FeatureMatchDropDown.Value;
            
        end

        % Value changed function: NumberofsamplesSpinner
        function NumberofsamplesSpinnerValueChanged(app, event)
            value = app.NumberofsamplesSpinner.Value;
            
        end

        % Value changed function: ConfidenceSpinner
        function ConfidenceSpinnerValueChanged(app, event)
            value = app.ConfidenceSpinner.Value;
            
        end

        % Value changed function: DistancethresholdSpinner
        function DistancethresholdSpinnerValueChanged(app, event)
            value = app.DistancethresholdSpinner.Value;
            
        end

        % Button pushed function: EpipolarLinesRightButton
        function EpipolarLinesRightButtonPushed(app, event)
            if isempty(app.LeftImage) || isempty(app.RightImage)
                uialert(app.UIFigure, 'Please load both images first!', 'Error');
                return;
            end
        
            I1 = im2gray(app.LeftImage);
            I2 = im2gray(app.RightImage);
        
            featType = app.FeatureMatchDropDown.Value;
            
            switch featType
                case 'Harris'
                    pts1 = detectHarrisFeatures(I1);
                    pts2 = detectHarrisFeatures(I2);
                case 'SURF'
                    pts1 = detectSURFFeatures(I1);
                    pts2 = detectSURFFeatures(I2);
                case 'SIFT'
                    try
                        pts1 = detectSIFTFeatures(I1);
                        pts2 = detectSIFTFeatures(I2);
                    catch
                        uialert(app.UIFigure, 'SIFT is not supported in your MATLAB version.', 'Version Error');
                        return;
                    end
                case 'precomputed'
                    uialert(app.UIFigure, 'Precomputed feature logic is not defined yet.', 'Information');
                    return;
                case 'Non'
                    uialert(app.UIFigure, 'Please select a Feature Matching method first.', 'Selection Error');
                    return;
                otherwise
                    pts1 = detectSURFFeatures(I1);
                    pts2 = detectSURFFeatures(I2);
            end
        
            [f1, vpts1] = extractFeatures(I1, pts1);
            [f2, vpts2] = extractFeatures(I2, pts2);
            indexPairs = matchFeatures(f1, f2);
        
            if size(indexPairs, 1) < 8
                uialert(app.UIFigure, ['Not enough matches with ' featType '. Need at least 8 points.'], 'Matching Error');
                return;
            end
        
            matchedPoints1 = vpts1(indexPairs(:, 1));
            matchedPoints2 = vpts2(indexPairs(:, 2));
        
            try
                [F, inlierIdx] = estimateFundamentalMatrix(matchedPoints1, matchedPoints2, ...
                    'Method', 'RANSAC', ...
                    'NumTrials', app.NumberofsamplesSpinner.Value, ...
                    'Confidence', app.ConfidenceSpinner.Value, ...
                    'DistanceThreshold', app.DistancethresholdSpinner.Value);
        
                if sum(inlierIdx) < 8
                    uialert(app.UIFigure, 'RANSAC failed to find 8 consistent points.', 'RANSAC Error');
                    return;
                end
        
                inlierPoints1 = matchedPoints1(inlierIdx, :);
                epiLines = epipolarLine(F, inlierPoints1.Location); 
                points = lineToBorderPoints(epiLines, size(I2));
        
                cla(app.UIAxes4); cla(app.UIAxes5);
                imshow(app.LeftImage, 'Parent', app.UIAxes4);
                imshow(app.RightImage, 'Parent', app.UIAxes5);
        
                hold(app.UIAxes5, 'on');
                line(app.UIAxes5, [points(:,1) points(:,3)]', [points(:,2) points(:,4)]', 'Color', 'green');
                hold(app.UIAxes5, 'off');
                
                hold(app.UIAxes4, 'on');
                plot(app.UIAxes4, inlierPoints1.Location(:,1), inlierPoints1.Location(:,2), 'ro', 'MarkerSize', 8);
                hold(app.UIAxes4, 'off');
        
            catch ME
                uialert(app.UIFigure, ['Mathematical Error: ' ME.message], 'Processing Error');
            end

        end

        % Button pushed function: EpipolarLinesLeftButton
        function EpipolarLinesLeftButtonPushed(app, event)
            if isempty(app.LeftImage) || isempty(app.RightImage)
                uialert(app.UIFigure, 'Please load both images first!', 'Error');
                return;
            end
        
            I1 = im2gray(app.LeftImage);
            I2 = im2gray(app.RightImage);
        
            featType = app.FeatureMatchDropDown.Value;
            
            switch featType
                case 'Harris'
                    pts1 = detectHarrisFeatures(I1);
                    pts2 = detectHarrisFeatures(I2);
                case 'SURF'
                    pts1 = detectSURFFeatures(I1);
                    pts2 = detectSURFFeatures(I2);
                case 'SIFT'
                    try
                        pts1 = detectSIFTFeatures(I1);
                        pts2 = detectSIFTFeatures(I2);
                    catch
                        uialert(app.UIFigure, 'SIFT is not supported or Toolbox is missing.', 'Version Error');
                        return;
                    end
                case 'precomputed'
                    uialert(app.UIFigure, 'Please provide a file for precomputed features.', 'Info');
                    return;
                case 'Non'
                    uialert(app.UIFigure, 'Please select a valid Feature Match method.', 'Selection Error');
                    return;
                otherwise
                    pts1 = detectSURFFeatures(I1);
                    pts2 = detectSURFFeatures(I2);
            end
        
            [f1, vpts1] = extractFeatures(I1, pts1);
            [f2, vpts2] = extractFeatures(I2, pts2);
            indexPairs = matchFeatures(f1, f2);
        
            if size(indexPairs, 1) < 8
                uialert(app.UIFigure, ['Not enough matches found using ' featType '. Try another method.'], 'Matching Error');
                return;
            end
        
            matchedPoints1 = vpts1(indexPairs(:, 1));
            matchedPoints2 = vpts2(indexPairs(:, 2));
        
            try
                [F, inlierIdx] = estimateFundamentalMatrix(matchedPoints1, matchedPoints2, ...
                    'Method', 'RANSAC', ...
                    'NumTrials', app.NumberofsamplesSpinner.Value, ...
                    'Confidence', app.ConfidenceSpinner.Value, ...
                    'DistanceThreshold', app.DistancethresholdSpinner.Value);
        
                if sum(inlierIdx) < 8
                     uialert(app.UIFigure, 'RANSAC could not find 8 valid inliers.', 'RANSAC Error');
                     return;
                end
        
                inlierPoints2 = matchedPoints2(inlierIdx, :);
                epiLinesLeft = epipolarLine(F', inlierPoints2.Location);
                pointsLeft = lineToBorderPoints(epiLinesLeft, size(I1));
        
                cla(app.UIAxes4); cla(app.UIAxes5);
                imshow(app.LeftImage, 'Parent', app.UIAxes4);
                imshow(app.RightImage, 'Parent', app.UIAxes5);
        
                hold(app.UIAxes4, 'on');
                line(app.UIAxes4, [pointsLeft(:,1) pointsLeft(:,3)]', [pointsLeft(:,2) pointsLeft(:,4)]', 'Color', 'cyan', 'LineWidth', 1);
                hold(app.UIAxes4, 'off');
                
                hold(app.UIAxes5, 'on');
                plot(app.UIAxes5, inlierPoints2.Location(:,1), inlierPoints2.Location(:,2), 'bo', 'MarkerSize', 8);
                hold(app.UIAxes5, 'off');
        
            catch ME
                uialert(app.UIFigure, ['Math Error: ' ME.message], 'Processing Error');
            end

        end

        % Button pushed function: LoadLeftImageButton
        function LoadLeftImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif'}, 'Select Left Image');
            if isequal(file, 0), return; end
            app.LeftImage = imread(fullfile(path, file));
            imshow(app.LeftImage, 'Parent', app.UIAxes4);
        end

        % Button pushed function: LoadRightImageButton
        function LoadRightImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif'}, 'Select Right Image');
            if isequal(file, 0), return; end
            app.RightImage = imread(fullfile(path, file));
            imshow(app.RightImage, 'Parent', app.UIAxes5);
        end

        % Button pushed function: DoneHoughLineButton
        function DoneHoughLineButtonPushed(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end
        
            I = app.WorkingImage;
            if size(I, 3) == 3, I = im2gray(I); end
            BW = edge(I, 'canny'); 
        
            numPeaks  = round(app.NumPeaksSpinner.Value);   
            fillGap   = app.FillGrapSpinner.Value;           
            minLength = app.MinLengthSpinner.Value;         
            [H, theta, rho] = hough(BW);
            
            P = houghpeaks(H, numPeaks, 'threshold', ceil(0.3 * max(H(:))));
            
            lines = houghlines(BW, theta, rho, P, 'FillGap', fillGap, 'MinLength', minLength);
        
            cla(app.UIAxes);
            imshow(app.WorkingImage, 'Parent', app.UIAxes);
            hold(app.UIAxes, 'on');
            
            for k = 1:length(lines)
                xy = [lines(k).point1; lines(k).point2];
                plot(app.UIAxes, xy(:,1), xy(:,2), 'LineWidth', 2, 'Color', 'green');
                plot(app.UIAxes, xy(1,1), xy(1,2), 'x', 'LineWidth', 2, 'Color', 'yellow');
                plot(app.UIAxes, xy(2,1), xy(2,2), 'x', 'LineWidth', 2, 'Color', 'red');
            end
            
            hold(app.UIAxes, 'off');
            title(app.UIAxes, ['Lines Detected: ', num2str(length(lines))]);

        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)
            
        end

        % Button pushed function: Generate3DStructureButton
        function Generate3DStructureButtonPushed(app, event)
            if isempty(app.LeftImage) || isempty(app.RightImage)
                    uialert(app.UIFigure, 'Please load both Left and Right images first!', 'Error');
                    return;
                end
            
                I1 = im2gray(app.LeftImage);
                I2 = im2gray(app.RightImage);
            
                if size(I1, 1) ~= size(I2, 1) || size(I1, 2) ~= size(I2, 2)
                    I2 = imresize(I2, size(I1)); 
                end
            
                try
                    
                    disparityMap = disparitySGM(I1, I2, 'DisparityRange', [0 64]);
            
                    fig = figure('Name', 'Bonus: 3D Structure from Motion', 'Color', 'w');
                    
                    [X, Y] = meshgrid(1:size(disparityMap, 2), 1:size(disparityMap, 1));
                    surf(X, Y, disparityMap, 'EdgeColor', 'none'); 
                    
                    colormap jet;
                    colorbar;
                    set(gca, 'ZDir', 'reverse'); 
                    title('3D Reconstructed Structure (Bonus)');
                    xlabel('X (Pixels)'); ylabel('Y (Pixels)'); zlabel('Depth (Disparity)');
                    view(3); 
                    axis tight;
                    rotate3d on; 
            
                catch ME
                    uialert(app.UIFigure, ['Error in 3D Reconstruction: ' ME.message], 'Processing Error');
                end

        end

        % Button pushed function: ApplyNoiseButton
        function ApplyNoiseButtonPushed(app, event)
            if isempty(app.WorkingImage)
                uialert(app.UIFigure, 'Please load an image first!', 'Error');
                return;
            end
            
            noiseAmount = app.NoiseLevelSlider.Value / 100;
            
            app.WorkingImage = imnoise(app.WorkingImage, 'salt & pepper', noiseAmount);
            
            imshow(app.WorkingImage, 'Parent', app.UIAxes3);
            title(app.UIAxes3, ['Image with ', num2str(app.NoiseLevelSlider.Value), '% Noise']);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Processed')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ZTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Original')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed3, true);
            app.ResetButton.Text = 'Reset';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.LoadTemplateButton = uibutton(app.UIFigure, 'state');
            app.LoadTemplateButton.ValueChangedFcn = createCallbackFcn(app, @LoadTemplateButtonValueChanged, true);
            app.LoadTemplateButton.Text = 'Load Template ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.SpatialdomainFilteringLabel = uilabel(app.Tab);
            app.SpatialdomainFilteringLabel.Text = ' Spatial-domain Filtering:';
            app.ColorSpaceconversionLabel = uilabel(app.Tab);
            app.ColorSpaceconversionLabel.Text = ' Color Space conversion:';
            app.FrequencydomainfilteringLabel = uilabel(app.Tab);
            app.FrequencydomainfilteringLabel.Text = ' Frequency-domain filtering:';
            app.imagesmoothingDropDownLabel = uilabel(app.Tab);
            app.imagesmoothingDropDownLabel.Text = ' image smoothing';
            app.imagesmoothingDropDown = uidropdown(app.Tab);
            app.imagesmoothingDropDown.Items = {'Non', 'Box filter - size 3', 'Box filter - size 5', 'Box filter - size 7', 'Box filter - size 9', 'Average filter - size 3', 'Average filter - size 5', 'Average filter - size 7', 'Average filter - size 9', 'Median filter - size 3', 'Median filter - size 5', 'Median filter - size 7', 'Median filter - size 9'};
            app.imagesmoothingDropDown.ValueChangedFcn = createCallbackFcn(app, @imagesmoothingDropDownValueChanged, true);
            app.imagesmoothingDropDown.Value = 'Non';
            app.imagesharpeningDropDownLabel = uilabel(app.Tab);
            app.imagesharpeningDropDownLabel.Text = ' image sharpening';
            app.imagesharpeningDropDown = uidropdown(app.Tab);
            app.imagesharpeningDropDown.Items = {'Non', 'Laplacian filter - 1st', 'Laplacian filter - 2nd', 'Boosted Laplacian filter - 1st', 'Boosted Laplacian filter - 2nd', 'Horizontal Sobel filter', 'Vertical Sobel filter', 'Horizontal Prewitt filter', 'Vertical Prewitt filter'};
            app.imagesharpeningDropDown.ValueChangedFcn = createCallbackFcn(app, @imagesharpeningDropDownValueChanged, true);
            app.imagesharpeningDropDown.Value = 'Non';
            app.imagesmoothingDropDown_2Label = uilabel(app.Tab);
            app.imagesmoothingDropDown_2Label.Text = ' image smoothing';
            app.imagesmoothingDropDown_2 = uidropdown(app.Tab);
            app.imagesmoothingDropDown_2.Items = {'Non', 'Ideal', 'Butterworth', 'Gaussian low-pass filtering'};
            app.imagesmoothingDropDown_2.ValueChangedFcn = createCallbackFcn(app, @imagesmoothingDropDown_2ValueChanged, true);
            app.imagesmoothingDropDown_2.Value = 'Non';
            app.imagesharpeningDropDown_2Label = uilabel(app.Tab);
            app.imagesharpeningDropDown_2Label.Text = ' image sharpening';
            app.imagesharpeningDropDown_2 = uidropdown(app.Tab);
            app.imagesharpeningDropDown_2.Items = {'Non', 'Ideal', 'Butterworth', 'Gaussian high-pass filtering'};
            app.imagesharpeningDropDown_2.ValueChangedFcn = createCallbackFcn(app, @imagesharpeningDropDown_2ValueChanged, true);
            app.imagesharpeningDropDown_2.Value = 'Non';
            app.RGBDropDownLabel = uilabel(app.Tab);
            app.RGBDropDownLabel.Text = 'RGB';
            app.RGBDropDown = uidropdown(app.Tab);
            app.RGBDropDown.Items = {'Non', 'Full', 'R', 'G', 'B'};
            app.RGBDropDown.ValueChangedFcn = createCallbackFcn(app, @RGBDropDownValueChanged, true);
            app.RGBDropDown.Value = 'Non';
            app.HSIDropDownLabel = uilabel(app.Tab);
            app.HSIDropDownLabel.Text = 'HSI';
            app.HSIDropDown = uidropdown(app.Tab);
            app.HSIDropDown.Items = {'Non', 'Full', 'H', 'S', 'I'};
            app.HSIDropDown.ValueChangedFcn = createCallbackFcn(app, @HSIDropDownValueChanged, true);
            app.HSIDropDown.Value = 'Non';
            app.LabDropDownLabel = uilabel(app.Tab);
            app.LabDropDownLabel.Text = 'L*a*b';
            app.LabDropDown = uidropdown(app.Tab);
            app.LabDropDown.Items = {'Non', 'Full', 'L', 'A', 'B'};
            app.LabDropDown.ValueChangedFcn = createCallbackFcn(app, @LabDropDownValueChanged, true);
            app.LabDropDown.Value = 'Non';
            app.YCbCrDropDownLabel = uilabel(app.Tab);
            app.YCbCrDropDownLabel.Text = 'YCbCr';
            app.YCbCrDropDown = uidropdown(app.Tab);
            app.YCbCrDropDown.Items = {'Non', 'Full', 'Y', 'Cb', 'Cr'};
            app.YCbCrDropDown.ValueChangedFcn = createCallbackFcn(app, @YCbCrDropDownValueChanged, true);
            app.YCbCrDropDown.Value = 'Non';
            app.ImageEnhancementLabel = uilabel(app.Tab);
            app.ImageEnhancementLabel.Text = ' Image Enhancement:';
            app.IntensityLabel = uilabel(app.Tab);
            app.IntensityLabel.Text = 'Intensity Brightness';
            app.IntensityBrightnessSlider = uislider(app.Tab);
            app.IntensityBrightnessSlider.Limits = [-100 100];
            app.IntensityBrightnessSlider.MajorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.IntensityBrightnessSlider.MajorTickLabels = {'-100', '-90', '-80', '-70', '-60', '-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.IntensityBrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @IntensityBrightnessSliderValueChanged, true);
            app.IntensityBrightnessSlider.MinorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.histogramequalizationCheckBox = uicheckbox(app.Tab);
            app.histogramequalizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @histogramequalizationCheckBoxValueChanged, true);
            app.histogramequalizationCheckBox.Text = 'histogram equalization';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.PyramidsLabel = uilabel(app.Tab2);
            app.PyramidsLabel.Text = 'Pyramids:';
            app.TemplateMatchingLabel = uilabel(app.Tab2);
            app.TemplateMatchingLabel.Text = 'Template Matching:';
            app.EdgeDetectionCannyLabel = uilabel(app.Tab2);
            app.EdgeDetectionCannyLabel.Text = 'Edge Detection : Canny';
            app.FilterBanksLabel = uilabel(app.Tab2);
            app.FilterBanksLabel.Text = 'FilterBanks :';
            app.DoneFBButton = uibutton(app.Tab2, 'push');
            app.DoneFBButton.ButtonPushedFcn = createCallbackFcn(app, @DoneFBButtonPushed, true);
            app.DoneFBButton.Text = 'Done FB';
            app.DoneCannyButton = uibutton(app.Tab2, 'push');
            app.DoneCannyButton.ButtonPushedFcn = createCallbackFcn(app, @DoneCannyButtonPushed, true);
            app.DoneCannyButton.Text = 'Done Canny';
            app.CornerDetectionHarrisLabel = uilabel(app.Tab2);
            app.CornerDetectionHarrisLabel.Text = 'Corner Detection : Harris';
            app.DoneHarrisButton = uibutton(app.Tab2, 'push');
            app.DoneHarrisButton.ButtonPushedFcn = createCallbackFcn(app, @DoneHarrisButtonPushed, true);
            app.DoneHarrisButton.Text = 'Done Harris';
            app.SelectSpecificButton = uibutton(app.Tab2, 'push');
            app.SelectSpecificButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSpecificButtonPushed, true);
            app.SelectSpecificButton.Text = 'Select Specific ';
            app.GaussianSliderLabel = uilabel(app.Tab2);
            app.GaussianSliderLabel.Text = 'Gaussian ';
            app.GaussianSlider = uislider(app.Tab2);
            app.GaussianSlider.Limits = [-3 3];
            app.GaussianSlider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.GaussianSlider.ValueChangedFcn = createCallbackFcn(app, @GaussianSliderValueChanged, true);
            app.GaussianSlider.MinorTicks = [-3 -2 -1 0 1 2 3];
            app.DropDownLabel = uilabel(app.Tab2);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Tab2);
            app.DropDown.Items = {'Non', 'NCC', 'ZNCC', 'SSD'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Non';
            app.LMDropDownLabel = uilabel(app.Tab2);
            app.LMDropDownLabel.Text = 'LM';
            app.LMDropDown = uidropdown(app.Tab2);
            app.LMDropDown.Items = {'Non', 'Spots', 'Bars', 'Edges'};
            app.LMDropDown.ValueChangedFcn = createCallbackFcn(app, @LMDropDownValueChanged, true);
            app.LMDropDown.Value = 'Non';
            app.LaplacianSliderLabel = uilabel(app.Tab2);
            app.LaplacianSliderLabel.Text = 'Laplacian';
            app.LaplacianSlider = uislider(app.Tab2);
            app.LaplacianSlider.Limits = [-3 3];
            app.LaplacianSlider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.LaplacianSlider.ValueChangedFcn = createCallbackFcn(app, @LaplacianSliderValueChanged, true);
            app.LaplacianSlider.MinorTicks = 0;
            app.OriantationDropDownLabel = uilabel(app.Tab2);
            app.OriantationDropDownLabel.Text = 'Oriantation';
            app.OriantationDropDown = uidropdown(app.Tab2);
            app.OriantationDropDown.Items = {'0', '30', '60', '90', '120', '150'};
            app.OriantationDropDown.Value = '0';
            app.ScaleDropDownLabel = uilabel(app.Tab2);
            app.ScaleDropDownLabel.Text = 'Scale';
            app.ScaleDropDown = uidropdown(app.Tab2);
            app.ScaleDropDown.Items = {'1', '2', '3', '4'};
            app.ScaleDropDown.Value = '1';
            app.sigmaSliderLabel = uilabel(app.Tab2);
            app.sigmaSliderLabel.Text = 'sigma';
            app.sigmaSlider = uislider(app.Tab2);
            app.sigmaSlider.Limits = [0 5];
            app.sigmaSlider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @sigmaSliderValueChanged, true);
            app.sigmaSlider.MinorTicks = 0;
            app.ThresholdSliderLabel = uilabel(app.Tab2);
            app.ThresholdSliderLabel.Text = 'Threshold ';
            app.ThresholdSlider = uislider(app.Tab2);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = 0;
            app.MinCornerStrengthSpinnerLabel = uilabel(app.Tab2);
            app.MinCornerStrengthSpinnerLabel.Text = 'Min Corner Strength';
            app.MinCornerStrengthSpinner = uispinner(app.Tab2);
            app.MinCornerStrengthSpinner.ValueChangedFcn = createCallbackFcn(app, @MinCornerStrengthSpinnerValueChanged, true);
            app.MinCornerStrengthSpinner.Value = 0.3;
            app.NeighborhoodSizeSpinnerLabel = uilabel(app.Tab2);
            app.NeighborhoodSizeSpinnerLabel.Text = 'Neighborhood Size';
            app.NeighborhoodSizeSpinner = uispinner(app.Tab2);
            app.NeighborhoodSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @NeighborhoodSizeSpinnerValueChanged, true);
            app.NeighborhoodSizeSpinner.Value = 3;
            app.NumberofCornersSpinnerLabel = uilabel(app.Tab2);
            app.NumberofCornersSpinnerLabel.Text = 'Number of Corners';
            app.NumberofCornersSpinner = uispinner(app.Tab2);
            app.NumberofCornersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofCornersSpinnerValueChanged, true);
            app.NumberofCornersSpinner.Value = 3;
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.HoGLabel = uilabel(app.Tab3);
            app.HoGLabel.Text = 'HoG :';
            app.VisualizationCheckBox = uicheckbox(app.Tab3);
            app.VisualizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualizationCheckBoxValueChanged, true);
            app.VisualizationCheckBox.Text = 'Visualization';
            app.DoneHoGButton = uibutton(app.Tab3, 'push');
            app.DoneHoGButton.ButtonPushedFcn = createCallbackFcn(app, @DoneHoGButtonPushed, true);
            app.DoneHoGButton.Text = 'Done HoG';
            app.CellSizeSpinnerLabel = uilabel(app.Tab3);
            app.CellSizeSpinnerLabel.Text = 'Cell Size';
            app.CellSizeSpinner = uispinner(app.Tab3);
            app.CellSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @CellSizeSpinnerValueChanged, true);
            app.BinNumberSpinnerLabel = uilabel(app.Tab3);
            app.BinNumberSpinnerLabel.Text = 'Bin Number';
            app.BinNumberSpinner = uispinner(app.Tab3);
            app.BinNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @BinNumberSpinnerValueChanged, true);
            app.BlockSizeSpinnerLabel = uilabel(app.Tab3);
            app.BlockSizeSpinnerLabel.Text = 'Block Size';
            app.BlockSizeSpinner = uispinner(app.Tab3);
            app.BlockSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @BlockSizeSpinnerValueChanged, true);
            app.NormalizationDropDownLabel = uilabel(app.Tab3);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.Tab3);
            app.NormalizationDropDown.Items = {'Non', 'L1', 'L2'};
            app.NormalizationDropDown.ValueChangedFcn = createCallbackFcn(app, @NormalizationDropDownValueChanged, true);
            app.NormalizationDropDown.Value = 'Non';
            app.GradientmethodDropDownLabel = uilabel(app.Tab3);
            app.GradientmethodDropDownLabel.Text = 'Gradient method';
            app.GradientmethodDropDown = uidropdown(app.Tab3);
            app.GradientmethodDropDown.Items = {'Non', 'Sobel', 'Prewitt'};
            app.GradientmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @GradientmethodDropDownValueChanged, true);
            app.GradientmethodDropDown.Value = 'Non';
            app.DoGLoGLabel = uilabel(app.Tab3);
            app.DoGLoGLabel.Text = 'DoG/LoG :';
            app.DoneDoGLoGButton = uibutton(app.Tab3, 'push');
            app.DoneDoGLoGButton.ButtonPushedFcn = createCallbackFcn(app, @DoneDoGLoGButtonPushed, true);
            app.DoneDoGLoGButton.Text = 'Done DoG/LoG';
            app.TypeDropDownLabel = uilabel(app.Tab3);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.Tab3);
            app.TypeDropDown.Items = {'Non', 'DoG', 'LoG'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Non';
            app.sigma_DoGLoGSliderLabel = uilabel(app.Tab3);
            app.sigma_DoGLoGSliderLabel.Text = 'sigma_DoG/LoG';
            app.sigma_DoGLoGSlider = uislider(app.Tab3);
            app.sigma_DoGLoGSlider.Limits = [0 5];
            app.sigma_DoGLoGSlider.MajorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.sigma_DoGLoGSlider.ValueChangedFcn = createCallbackFcn(app, @sigma_DoGLoGSliderValueChanged2, true);
            app.sigma_DoGLoGSlider.MinorTicks = 0;
            app.ScaleFactorSliderLabel = uilabel(app.Tab3);
            app.ScaleFactorSliderLabel.Text = 'Scale Factor';
            app.ScaleFactorSlider = uislider(app.Tab3);
            app.ScaleFactorSlider.Limits = [0 2];
            app.ScaleFactorSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2];
            app.ScaleFactorSlider.ValueChangedFcn = createCallbackFcn(app, @ScaleFactorSliderValueChanged, true);
            app.ScaleFactorSlider.MinorTicks = 0;
            app.numberofscalesSpinnerLabel = uilabel(app.Tab3);
            app.numberofscalesSpinnerLabel.Text = 'number of scales';
            app.numberofscalesSpinner = uispinner(app.Tab3);
            app.numberofscalesSpinner.ValueChangedFcn = createCallbackFcn(app, @numberofscalesSpinnerValueChanged, true);
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.RANSACLabel = uilabel(app.Tab4);
            app.RANSACLabel.Text = 'RANSAC :';
            app.DoneRANSACButton = uibutton(app.Tab4, 'push');
            app.DoneRANSACButton.ButtonPushedFcn = createCallbackFcn(app, @DoneRANSACButtonPushed, true);
            app.DoneRANSACButton.Text = 'Done RANSAC';
            app.IterationsSpinnerLabel = uilabel(app.Tab4);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.Tab4);
            app.IterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @IterationsSpinnerValueChanged, true);
            app.IterationsSpinner.Value = 100;
            app.InlierThresholdSpinnerLabel = uilabel(app.Tab4);
            app.InlierThresholdSpinnerLabel.Text = 'Inlier Threshold';
            app.InlierThresholdSpinner = uispinner(app.Tab4);
            app.InlierThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @InlierThresholdSpinnerValueChanged, true);
            app.InlierThresholdSpinner.Value = 2;
            app.MinimumInliersSpinnerLabel = uilabel(app.Tab4);
            app.MinimumInliersSpinnerLabel.Text = 'Minimum Inliers';
            app.MinimumInliersSpinner = uispinner(app.Tab4);
            app.MinimumInliersSpinner.ValueChangedFcn = createCallbackFcn(app, @MinimumInliersSpinnerValueChanged, true);
            app.MinimumInliersSpinner.Value = 20;
            app.ModelTypeDropDownLabel = uilabel(app.Tab4);
            app.ModelTypeDropDownLabel.Text = 'Model Type';
            app.ModelTypeDropDown = uidropdown(app.Tab4);
            app.ModelTypeDropDown.Items = {'Non', 'Line', 'Circle'};
            app.ModelTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelTypeDropDownValueChanged, true);
            app.ModelTypeDropDown.Value = 'Non';
            app.NoiseLevelSliderLabel = uilabel(app.Tab4);
            app.NoiseLevelSliderLabel.Text = 'Noise Level';
            app.NoiseLevelSlider = uislider(app.Tab4);
            app.NoiseLevelSlider.Limits = [0 10];
            app.NoiseLevelSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.NoiseLevelSlider.ValueChangedFcn = createCallbackFcn(app, @NoiseLevelSliderValueChanged, true);
            app.NoiseLevelSlider.MinorTicks = 0;
            app.HoughTransformLabel = uilabel(app.Tab4);
            app.HoughTransformLabel.Text = 'Hough Transform :';
            app.LinesLabel = uilabel(app.Tab4);
            app.LinesLabel.Text = 'Lines :';
            app.CriclesLabel = uilabel(app.Tab4);
            app.CriclesLabel.Text = 'Cricles :';
            app.DoneHoughLineButton = uibutton(app.Tab4, 'push');
            app.DoneHoughLineButton.ButtonPushedFcn = createCallbackFcn(app, @DoneHoughLineButtonPushed, true);
            app.DoneHoughLineButton.Text = 'Done Hough Line';
            app.DoneHoughCircleButton = uibutton(app.Tab4, 'push');
            app.DoneHoughCircleButton.ButtonPushedFcn = createCallbackFcn(app, @DoneHoughCircleButtonPushed, true);
            app.DoneHoughCircleButton.Text = 'Done Hough Circle';
            app.NumPeaksSpinnerLabel = uilabel(app.Tab4);
            app.NumPeaksSpinnerLabel.Text = 'Num Peaks';
            app.NumPeaksSpinner = uispinner(app.Tab4);
            app.NumPeaksSpinner.ValueChangedFcn = createCallbackFcn(app, @NumPeaksSpinnerValueChanged, true);
            app.FillGrapSpinnerLabel = uilabel(app.Tab4);
            app.FillGrapSpinnerLabel.Text = 'Fill Grap';
            app.FillGrapSpinner = uispinner(app.Tab4);
            app.FillGrapSpinner.ValueChangedFcn = createCallbackFcn(app, @FillGrapSpinnerValueChanged, true);
            app.MinLengthSpinnerLabel = uilabel(app.Tab4);
            app.MinLengthSpinnerLabel.Text = 'Min Length';
            app.MinLengthSpinner = uispinner(app.Tab4);
            app.MinLengthSpinner.ValueChangedFcn = createCallbackFcn(app, @MinLengthSpinnerValueChanged, true);
            app.MinRadiusSpinnerLabel = uilabel(app.Tab4);
            app.MinRadiusSpinnerLabel.Text = 'MinRadius';
            app.MinRadiusSpinner = uispinner(app.Tab4);
            app.MinRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @MinRadiusSpinnerValueChanged, true);
            app.MaxRadiusSpinnerLabel = uilabel(app.Tab4);
            app.MaxRadiusSpinnerLabel.Text = 'MaxRadius';
            app.MaxRadiusSpinner = uispinner(app.Tab4);
            app.MaxRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxRadiusSpinnerValueChanged, true);
            app.ThersholdSpinnerLabel = uilabel(app.Tab4);
            app.ThersholdSpinnerLabel.Text = 'Thershold';
            app.ThersholdSpinner = uispinner(app.Tab4);
            app.ThersholdSpinner.ValueChangedFcn = createCallbackFcn(app, @ThersholdSpinnerValueChanged, true);
            app.PolarityDropDownLabel = uilabel(app.Tab4);
            app.PolarityDropDownLabel.Text = 'Polarity';
            app.PolarityDropDown = uidropdown(app.Tab4);
            app.PolarityDropDown.Items = {'Non', 'Dark', 'Light'};
            app.PolarityDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarityDropDownValueChanged, true);
            app.PolarityDropDown.Value = 'Non';
            app.ApplyNoiseButton = uibutton(app.Tab4, 'push');
            app.ApplyNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyNoiseButtonPushed, true);
            app.ApplyNoiseButton.Text = 'Apply Noise';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UIAxes5 = uiaxes(app.Tab5);
            title(app.UIAxes5, 'Right')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.UIAxes4 = uiaxes(app.Tab5);
            title(app.UIAxes4, 'Left')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.RANSACLabel_2 = uilabel(app.Tab5);
            app.RANSACLabel_2.Text = 'RANSAC';
            app.EpipolarLinesLeftButton = uibutton(app.Tab5, 'push');
            app.EpipolarLinesLeftButton.ButtonPushedFcn = createCallbackFcn(app, @EpipolarLinesLeftButtonPushed, true);
            app.EpipolarLinesLeftButton.Text = 'Epipolar Lines (Left)';
            app.EpipolarLinesRightButton = uibutton(app.Tab5, 'push');
            app.EpipolarLinesRightButton.ButtonPushedFcn = createCallbackFcn(app, @EpipolarLinesRightButtonPushed, true);
            app.EpipolarLinesRightButton.Text = 'Epipolar Lines (Right)';
            app.LoadRightImageButton = uibutton(app.Tab5, 'push');
            app.LoadRightImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRightImageButtonPushed, true);
            app.LoadRightImageButton.Text = 'Load Right Image';
            app.LoadLeftImageButton = uibutton(app.Tab5, 'push');
            app.LoadLeftImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLeftImageButtonPushed, true);
            app.LoadLeftImageButton.Text = 'Load Left Image';
            app.StereoVisionEpipolarLinesLabel = uilabel(app.Tab5);
            app.StereoVisionEpipolarLinesLabel.Text = 'Stereo Vision (Epipolar Lines)';
            app.FeatureMatchDropDownLabel = uilabel(app.Tab5);
            app.FeatureMatchDropDownLabel.Text = 'Feature Match';
            app.FeatureMatchDropDown = uidropdown(app.Tab5);
            app.FeatureMatchDropDown.Items = {'Non', 'Harris', 'SURF', 'SIFT', 'precomputed'};
            app.FeatureMatchDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureMatchDropDownValueChanged, true);
            app.FeatureMatchDropDown.Value = 'Non';
            app.NumberofsamplesSpinnerLabel = uilabel(app.Tab5);
            app.NumberofsamplesSpinnerLabel.Text = 'Number of samples';
            app.NumberofsamplesSpinner = uispinner(app.Tab5);
            app.NumberofsamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofsamplesSpinnerValueChanged, true);
            app.ConfidenceSpinnerLabel = uilabel(app.Tab5);
            app.ConfidenceSpinnerLabel.Text = 'Confidence';
            app.ConfidenceSpinner = uispinner(app.Tab5);
            app.ConfidenceSpinner.ValueChangedFcn = createCallbackFcn(app, @ConfidenceSpinnerValueChanged, true);
            app.DistancethresholdSpinnerLabel = uilabel(app.Tab5);
            app.DistancethresholdSpinnerLabel.Text = 'Distance threshold';
            app.DistancethresholdSpinner = uispinner(app.Tab5);
            app.DistancethresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @DistancethresholdSpinnerValueChanged, true);
            app.Generate3DStructureButton = uibutton(app.Tab5, 'push');
            app.Generate3DStructureButton.ButtonPushedFcn = createCallbackFcn(app, @Generate3DStructureButtonPushed, true);
            app.Generate3DStructureButton.Text = 'Generate 3D Structure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task1_AsyaAbdelFattah_

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end